classdef NertsV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NertMachine                    matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        TeamsTab_2                     matlab.ui.container.Tab
        NERTButton                     matlab.ui.control.Button
        NerterButtonGroup              matlab.ui.container.ButtonGroup
        Team1Button                    matlab.ui.control.RadioButton
        Team2Button                    matlab.ui.control.RadioButton
        HandEditField                  matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        T1Bonus5Button                 matlab.ui.control.Button
        T2Bonus5Button                 matlab.ui.control.Button
        T1Penalty5Button_2             matlab.ui.control.Button
        T2Penalty5Button_2             matlab.ui.control.Button
        CardsinHandLabel               matlab.ui.control.Label
        T1CardsinBushEditFieldLabel    matlab.ui.control.Label
        T1CardsinBushEditField         matlab.ui.control.NumericEditField
        T2CardsinBushEditFieldLabel    matlab.ui.control.Label
        T2CardsinBushEditField         matlab.ui.control.NumericEditField
        Team1EditFieldLabel            matlab.ui.control.Label
        Team1EditField                 matlab.ui.control.NumericEditField
        Team2EditFieldLabel            matlab.ui.control.Label
        Team2EditField                 matlab.ui.control.NumericEditField
        TeamsTab_3                     matlab.ui.container.Tab
        NERTButton_3                   matlab.ui.control.Button
        NerterButtonGroup_3            matlab.ui.container.ButtonGroup
        Team1Button_3                  matlab.ui.control.RadioButton
        Team2Button_3                  matlab.ui.control.RadioButton
        Team3Button                    matlab.ui.control.RadioButton
        HandEditField_11               matlab.ui.control.NumericEditField
        UIAxes_3                       matlab.ui.control.UIAxes
        T1Bonus5Button_3               matlab.ui.control.Button
        T2Bonus5Button_3               matlab.ui.control.Button
        T1Penalty5Button               matlab.ui.control.Button
        T2Penalty5Button               matlab.ui.control.Button
        CardsinHandLabel_11            matlab.ui.control.Label
        T1CardsinBushEditField_2Label  matlab.ui.control.Label
        T1CardsinBushEditField_3       matlab.ui.control.NumericEditField
        T2CardsinBushEditField_2Label  matlab.ui.control.Label
        T2CardsinBushEditField_3       matlab.ui.control.NumericEditField
        Team1EditField_2Label          matlab.ui.control.Label
        Team1EditField_3               matlab.ui.control.NumericEditField
        Team2EditField_2Label          matlab.ui.control.Label
        Team2EditField_3               matlab.ui.control.NumericEditField
        HandEditField_12               matlab.ui.control.NumericEditField
        CardsinHandLabel_12            matlab.ui.control.Label
        T3CardsinBushEditFieldLabel    matlab.ui.control.Label
        T3CardsinBushEditField         matlab.ui.control.NumericEditField
        Team3EditFieldLabel            matlab.ui.control.Label
        Team3EditField                 matlab.ui.control.NumericEditField
        T3Bonus5Button                 matlab.ui.control.Button
        T3Penalty5Button               matlab.ui.control.Button
        SettingsTab                    matlab.ui.container.Tab
        PointsaNertisWorthEditFieldLabel  matlab.ui.control.Label
        PointsaNertisWorthEditField    matlab.ui.control.NumericEditField
        PointsCardsinBushAreWorthEditFieldLabel  matlab.ui.control.Label
        PointsCardsinBushAreWorthEditField  matlab.ui.control.NumericEditField
        PointsCardsinHandAreWorthEditFieldLabel  matlab.ui.control.Label
        PointsCardsinHandAreWorthEditField  matlab.ui.control.NumericEditField
        RESETButton                    matlab.ui.control.Button
        Tab                            matlab.ui.container.Tab
        T1sEditFieldLabel              matlab.ui.control.Label
        T2sEditFieldLabel              matlab.ui.control.Label
        T2sEditField                   matlab.ui.control.NumericEditField
        CIHEditFieldLabel              matlab.ui.control.Label
        CIHEditField                   matlab.ui.control.NumericEditField
        CIBEditFieldLabel              matlab.ui.control.Label
        CIBEditField                   matlab.ui.control.NumericEditField
        NERTEditFieldLabel             matlab.ui.control.Label
        NERTEditField                  matlab.ui.control.NumericEditField
        T1sEditField                   matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        T1s = 0;
        T2s = 0;
        T3s = 0;
        rnd = 1;
        i = 1;
        nrt = 5;
        cih = 3;
        cib = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NERTButton
        function NERTButtonPushed(app, event)
            app.nrt = app.PointsaNertisWorthEditField.Value;
            app.cih = app.PointsCardsinHandAreWorthEditField.Value;
            app.cib = app.PointsCardsinBushAreWorthEditField.Value;
            
            
            if app.i == 1
                app.T1s(1) = 0;
                app.T2s(1) = 0;
                app.i = app.i+1;
            end
            if app.Team1Button.Value
                app.T1s(app.i) = app.T1s(end) + app.nrt + (app.T1CardsinBushEditField.Value*app.cib);
                app.T2s(app.i) = app.T2s(end) - (app.HandEditField.Value*app.cih) + (app.T2CardsinBushEditField.Value*app.cib);
                
            elseif app.Team2Button.Value
                app.T2s(app.i) = app.T2s(end) + app.nrt + (app.T2CardsinBushEditField.Value*app.cib);
                app.T1s(app.i) = app.T1s(end) - (app.HandEditField.Value*app.cih) + (app.T1CardsinBushEditField.Value*app.cib);
                
            end
            
            
            app.Team1EditField.Value = app.T1s(end);
            app.Team2EditField.Value = app.T2s(end);
            

            app.T1sEditField.Value = app.T1s(end);
            app.T2sEditField.Value = app.T2s(end);
            app.CIHEditField.Value = app.cih;
            app.CIBEditField.Value = app.cib;
            app.NERTEditField.Value = app.nrt;
            
            app.rnd = 0:length(app.T1s)-1;
            plot(app.UIAxes,app.rnd,app.T1s,app.rnd,app.T2s,'linewidth',3);
            legend(app.UIAxes,'Team 1','Team 2','location','northwest')
            xlim(app.UIAxes,[0 app.rnd(end)+1])
            %ylim(app.UIAxes,[(min(app.T1s(end),app.T2s(end))-20) (max(app.T1s(end),app.T2s(end))+20)])
            ylim([-100 100])
            xticks(app.UIAxes,0:1:app.rnd(end))
            
            app.i = app.i+1;
            app.HandEditField.Value = 0;
            app.T1CardsinBushEditField.Value = 0;
            app.T2CardsinBushEditField.Value = 0;
            
            
        end

        % Button pushed function: T1Bonus5Button
        function T1Bonus5ButtonPushed(app, event)
            app.T1s(app.i) = app.T1s(end) + 5;
            app.Team1EditField.Value = app.T1s(end);
        end

        % Button pushed function: T2Bonus5Button
        function T2Bonus5ButtonPushed(app, event)
            app.T2s(app.i) = app.T2s(end) + 5;
            app.Team2EditField.Value = app.T2s(end);
        end

        % Button pushed function: T1Penalty5Button_2
        function T1Penalty5Button_2Pushed(app, event)
            app.T1s(app.i) = app.T1s(end) - 5;
            app.Team1EditField.Value = app.T1s(end);
        end

        % Button pushed function: T2Penalty5Button_2
        function T2Penalty5Button_2Pushed(app, event)
            app.T2s(app.i) = app.T2s(end) - 5;
            app.Team2EditField.Value = app.T2s(end);
        end

        % Selection changed function: NerterButtonGroup
        function NerterButtonGroupSelectionChanged(app, event)

            if app.Team1Button.Value
                app.CardsinHandLabel.Text = 'T2 Cards in Hand';
            end
            if app.Team2Button.Value
                app.CardsinHandLabel.Text = 'T1 Cards in Hand';
            end
        end

        % Button pushed function: NERTButton_3
        function NERTButton_3Pushed(app, event)
            app.nrt = app.PointsaNertisWorthEditField.Value;
            app.cih = app.PointsCardsinHandAreWorthEditField.Value;
            app.cib = app.PointsCardsinBushAreWorthEditField.Value;
            
            
            if app.i == 1
                app.T1s(1) = 0;
                app.T2s(1) = 0;
                app.T3s(1) = 0;
                app.i = app.i+1;
            end
            if app.Team1Button_3.Value
                app.T1s(app.i) = app.T1s(end) + app.nrt + (app.T1CardsinBushEditField_3.Value*app.cib);
                app.T2s(app.i) = app.T2s(end) - (app.HandEditField_11.Value*app.cih) + (app.T2CardsinBushEditField_3.Value*app.cib);
                app.T3s(app.i) = app.T3s(end) - (app.HandEditField_12.Value*app.cih) + (app.T3CardsinBushEditField.Value*app.cib);
            elseif app.Team2Button_3.Value
                app.T2s(app.i) = app.T2s(end) + app.nrt + (app.T2CardsinBushEditField_3.Value*app.cib);
                app.T1s(app.i) = app.T1s(end) - (app.HandEditField_11.Value*app.cih) + (app.T1CardsinBushEditField_3.Value*app.cib);
                app.T3s(app.i) = app.T3s(end) - (app.HandEditField_12.Value*app.cih) + (app.T3CardsinBushEditField.Value*app.cib);
                
            elseif app.Team3Button.Value
                app.T3s(app.i) = app.T3s(end) + app.nrt + (app.T3CardsinBushEditField.Value*app.cib);
                app.T1s(app.i) = app.T1s(end) - (app.HandEditField_11.Value*app.cih) + (app.T1CardsinBushEditField_3.Value*app.cib);
                app.T2s(app.i) = app.T2s(end) - (app.HandEditField_12.Value*app.cih) + (app.T2CardsinBushEditField_3.Value*app.cib);
            end
            
            
            app.Team1EditField_3.Value = app.T1s(end);
            app.Team2EditField_3.Value = app.T2s(end);
            app.Team3EditField.Value = app.T3s(end);
            
            app.rnd = 0:length(app.T1s)-1;
            plot(app.UIAxes_3,app.rnd,app.T1s,app.rnd,app.T2s,app.rnd,app.T3s,'linewidth',3);
            legend(app.UIAxes_3,'Team 1','Team 2','Team 3','location','northwest')
            xlim(app.UIAxes_3,[0 app.rnd(end)+1])
            %ylim(app.UIAxes_3,[(min([app.T1s(end),app.T2s(end),app.T3s(end)])-20) (max([app.T1s(end),app.T2s(end),app.T3s(end)])+20)])
            ylim([-100 100])
            xticks(app.UIAxes_3,0:1:app.rnd(end))
            
            app.i = app.i+1;
            app.HandEditField_11.Value = 0;
            app.HandEditField_12.Value = 0;
            app.T1CardsinBushEditField_3.Value = 0;
            app.T2CardsinBushEditField_3.Value = 0;
            app.T3CardsinBushEditField.Value = 0;
        end

        % Selection changed function: NerterButtonGroup_3
        function NerterButtonGroup_3SelectionChanged(app, event)
            
            if app.Team1Button_3.Value
                app.CardsinHandLabel_11.Text = 'T2 Cards in Hand';
                app.CardsinHandLabel_12.Text = 'T3 Cards in Hand';
            end
            if app.Team2Button_3.Value
                app.CardsinHandLabel_11.Text = 'T1 Cards in Hand';
                app.CardsinHandLabel_12.Text = 'T3 Cards in Hand';
            end
            if app.Team3Button.Value
                app.CardsinHandLabel_11.Text = 'T1 Cards in Hand';
                app.CardsinHandLabel_12.Text = 'T2 Cards in Hand';
            end

        end

        % Button pushed function: T1Bonus5Button_3
        function T1Bonus5Button_3Pushed(app, event)
            app.T1s(app.i) = app.T1s(end) + 5;
            app.Team1EditField_3.Value = app.T1s(end);
        end

        % Button pushed function: T2Bonus5Button_3
        function T2Bonus5Button_3Pushed(app, event)
            app.T2s(app.i) = app.T2s(end) + 5;
            app.Team2EditField_3.Value = app.T2s(end);
        end

        % Button pushed function: T3Bonus5Button
        function T3Bonus5ButtonPushed(app, event)
            app.T3s(app.i) = app.T3s(end) + 5;
            app.Team3EditField.Value = app.T3s(end);
        end

        % Button pushed function: T1Penalty5Button
        function T1Penalty5ButtonPushed(app, event)
                        app.T1s(app.i) = app.T1s(end) - 5;
            app.Team1EditField_3.Value = app.T1s(end);
        end

        % Button pushed function: T2Penalty5Button
        function T2Penalty5ButtonPushed(app, event)
                        app.T2s(app.i) = app.T2s(end) - 5;
            app.Team2EditField_3.Value = app.T2s(end);
        end

        % Button pushed function: T3Penalty5Button
        function T3Penalty5ButtonPushed(app, event)
                        app.T3s(app.i) = app.T3s(end) - 5;
            app.Team3EditField.Value = app.T3s(end);
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.T1s = 0;
            app.T2s = 0;
            app.T3s = 0;
            
            app.Team1EditField.Value = 0;
            app.Team2EditField.Value = 0;
            
            app.Team1EditField_3.Value = 0;
            app.Team2EditField_3.Value = 0;
            app.Team3EditField.Value = 0;
            
            cla(app.UIAxes)
            cla(app.UIAxes_3)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NertMachine = uifigure('Visible', 'off');
            app.NertMachine.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.NertMachine);
            app.TeamsTab_2 = uitab(app.TabGroup);
            app.TeamsTab_2.Title = '2 Teams';
            app.NERTButton = uibutton(app.TeamsTab_2, 'push');
            app.NERTButton.ButtonPushedFcn = createCallbackFcn(app, @NERTButtonPushed, true);
            app.NERTButton.Text = 'NERT';
            app.NerterButtonGroup = uibuttongroup(app.TeamsTab_2);
            app.NerterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NerterButtonGroupSelectionChanged, true);
            app.NerterButtonGroup.Title = 'Nerter';
            app.Team1Button = uiradiobutton(app.NerterButtonGroup);
            app.Team1Button.Text = 'Team 1';
            app.Team1Button.Value = true;
            app.Team2Button = uiradiobutton(app.NerterButtonGroup);
            app.Team2Button.Text = 'Team 2';
            app.HandEditField = uieditfield(app.TeamsTab_2, 'numeric');
            app.HandEditField.RoundFractionalValues = 'on';
            app.UIAxes = uiaxes(app.TeamsTab_2);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Round')
            ylabel(app.UIAxes, 'Score')
            app.UIAxes.YLim = [-100 100];
            app.T1Bonus5Button = uibutton(app.TeamsTab_2, 'push');
            app.T1Bonus5Button.ButtonPushedFcn = createCallbackFcn(app, @T1Bonus5ButtonPushed, true);
            app.T1Bonus5Button.Text = 'T1 Bonus (+5)';
            app.T2Bonus5Button = uibutton(app.TeamsTab_2, 'push');
            app.T2Bonus5Button.ButtonPushedFcn = createCallbackFcn(app, @T2Bonus5ButtonPushed, true);
            app.T2Bonus5Button.Text = 'T2 Bonus (+5)';
            app.T1Penalty5Button_2 = uibutton(app.TeamsTab_2, 'push');
            app.T1Penalty5Button_2.ButtonPushedFcn = createCallbackFcn(app, @T1Penalty5Button_2Pushed, true);
            app.T1Penalty5Button_2.Text = 'T1 Penalty (-5)';
            app.T2Penalty5Button_2 = uibutton(app.TeamsTab_2, 'push');
            app.T2Penalty5Button_2.ButtonPushedFcn = createCallbackFcn(app, @T2Penalty5Button_2Pushed, true);
            app.T2Penalty5Button_2.Text = 'T2 Penalty (-5)';
            app.CardsinHandLabel = uilabel(app.TeamsTab_2);
            app.CardsinHandLabel.Text = {'Cards in Hand'; ''};
            app.T1CardsinBushEditFieldLabel = uilabel(app.TeamsTab_2);
            app.T1CardsinBushEditFieldLabel.Text = 'T1 Cards in Bush';
            app.T1CardsinBushEditField = uieditfield(app.TeamsTab_2, 'numeric');
            app.T1CardsinBushEditField.RoundFractionalValues = 'on';
            app.T2CardsinBushEditFieldLabel = uilabel(app.TeamsTab_2);
            app.T2CardsinBushEditFieldLabel.Text = 'T2 Cards in Bush';
            app.T2CardsinBushEditField = uieditfield(app.TeamsTab_2, 'numeric');
            app.T2CardsinBushEditField.RoundFractionalValues = 'on';
            app.Team1EditFieldLabel = uilabel(app.TeamsTab_2);
            app.Team1EditFieldLabel.Text = 'Team 1';
            app.Team1EditField = uieditfield(app.TeamsTab_2, 'numeric');
            app.Team2EditFieldLabel = uilabel(app.TeamsTab_2);
            app.Team2EditFieldLabel.Text = 'Team 2';
            app.Team2EditField = uieditfield(app.TeamsTab_2, 'numeric');
            app.TeamsTab_3 = uitab(app.TabGroup);
            app.TeamsTab_3.Title = '3 Teams';
            app.NERTButton_3 = uibutton(app.TeamsTab_3, 'push');
            app.NERTButton_3.ButtonPushedFcn = createCallbackFcn(app, @NERTButton_3Pushed, true);
            app.NERTButton_3.Text = 'NERT';
            app.NerterButtonGroup_3 = uibuttongroup(app.TeamsTab_3);
            app.NerterButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @NerterButtonGroup_3SelectionChanged, true);
            app.NerterButtonGroup_3.Title = 'Nerter';
            app.Team1Button_3 = uiradiobutton(app.NerterButtonGroup_3);
            app.Team1Button_3.Text = 'Team 1';
            app.Team1Button_3.Value = true;
            app.Team2Button_3 = uiradiobutton(app.NerterButtonGroup_3);
            app.Team2Button_3.Text = 'Team 2';
            app.Team3Button = uiradiobutton(app.NerterButtonGroup_3);
            app.Team3Button.Text = 'Team 3';
            app.HandEditField_11 = uieditfield(app.TeamsTab_3, 'numeric');
            app.HandEditField_11.RoundFractionalValues = 'on';
            app.UIAxes_3 = uiaxes(app.TeamsTab_3);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, 'Round')
            ylabel(app.UIAxes_3, 'Score')
            app.UIAxes_3.YLim = [-100 100];
            app.T1Bonus5Button_3 = uibutton(app.TeamsTab_3, 'push');
            app.T1Bonus5Button_3.ButtonPushedFcn = createCallbackFcn(app, @T1Bonus5Button_3Pushed, true);
            app.T1Bonus5Button_3.Text = 'T1 Bonus (+5)';
            app.T2Bonus5Button_3 = uibutton(app.TeamsTab_3, 'push');
            app.T2Bonus5Button_3.ButtonPushedFcn = createCallbackFcn(app, @T2Bonus5Button_3Pushed, true);
            app.T2Bonus5Button_3.Text = 'T2 Bonus (+5)';
            app.T1Penalty5Button = uibutton(app.TeamsTab_3, 'push');
            app.T1Penalty5Button.ButtonPushedFcn = createCallbackFcn(app, @T1Penalty5ButtonPushed, true);
            app.T1Penalty5Button.Text = 'T1 Penalty (-5)';
            app.T2Penalty5Button = uibutton(app.TeamsTab_3, 'push');
            app.T2Penalty5Button.ButtonPushedFcn = createCallbackFcn(app, @T2Penalty5ButtonPushed, true);
            app.T2Penalty5Button.Text = 'T2 Penalty (-5)';
            app.CardsinHandLabel_11 = uilabel(app.TeamsTab_3);
            app.CardsinHandLabel_11.Text = {'Cards in Hand'; ''};
            app.T1CardsinBushEditField_2Label = uilabel(app.TeamsTab_3);
            app.T1CardsinBushEditField_2Label.Text = 'T1 Cards in Bush';
            app.T1CardsinBushEditField_3 = uieditfield(app.TeamsTab_3, 'numeric');
            app.T1CardsinBushEditField_3.RoundFractionalValues = 'on';
            app.T2CardsinBushEditField_2Label = uilabel(app.TeamsTab_3);
            app.T2CardsinBushEditField_2Label.Text = 'T2 Cards in Bush';
            app.T2CardsinBushEditField_3 = uieditfield(app.TeamsTab_3, 'numeric');
            app.T2CardsinBushEditField_3.RoundFractionalValues = 'on';
            app.Team1EditField_2Label = uilabel(app.TeamsTab_3);
            app.Team1EditField_2Label.Text = 'Team 1';
            app.Team1EditField_3 = uieditfield(app.TeamsTab_3, 'numeric');
            app.Team2EditField_2Label = uilabel(app.TeamsTab_3);
            app.Team2EditField_2Label.Text = 'Team 2';
            app.Team2EditField_3 = uieditfield(app.TeamsTab_3, 'numeric');
            app.HandEditField_12 = uieditfield(app.TeamsTab_3, 'numeric');
            app.HandEditField_12.RoundFractionalValues = 'on';
            app.CardsinHandLabel_12 = uilabel(app.TeamsTab_3);
            app.CardsinHandLabel_12.Text = {'Cards in Hand'; ''};
            app.T3CardsinBushEditFieldLabel = uilabel(app.TeamsTab_3);
            app.T3CardsinBushEditFieldLabel.Text = 'T3 Cards in Bush';
            app.T3CardsinBushEditField = uieditfield(app.TeamsTab_3, 'numeric');
            app.T3CardsinBushEditField.RoundFractionalValues = 'on';
            app.Team3EditFieldLabel = uilabel(app.TeamsTab_3);
            app.Team3EditFieldLabel.Text = 'Team 3';
            app.Team3EditField = uieditfield(app.TeamsTab_3, 'numeric');
            app.T3Bonus5Button = uibutton(app.TeamsTab_3, 'push');
            app.T3Bonus5Button.ButtonPushedFcn = createCallbackFcn(app, @T3Bonus5ButtonPushed, true);
            app.T3Bonus5Button.Text = 'T3 Bonus (+5)';
            app.T3Penalty5Button = uibutton(app.TeamsTab_3, 'push');
            app.T3Penalty5Button.ButtonPushedFcn = createCallbackFcn(app, @T3Penalty5ButtonPushed, true);
            app.T3Penalty5Button.Text = 'T3 Penalty (-5)';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.PointsaNertisWorthEditFieldLabel = uilabel(app.SettingsTab);
            app.PointsaNertisWorthEditFieldLabel.Text = 'Points a Nert is Worth';
            app.PointsaNertisWorthEditField = uieditfield(app.SettingsTab, 'numeric');
            app.PointsaNertisWorthEditField.Value = 5;
            app.PointsCardsinBushAreWorthEditFieldLabel = uilabel(app.SettingsTab);
            app.PointsCardsinBushAreWorthEditFieldLabel.Text = 'Points Cards in Bush Are Worth';
            app.PointsCardsinBushAreWorthEditField = uieditfield(app.SettingsTab, 'numeric');
            app.PointsCardsinBushAreWorthEditField.Value = 1;
            app.PointsCardsinHandAreWorthEditFieldLabel = uilabel(app.SettingsTab);
            app.PointsCardsinHandAreWorthEditFieldLabel.Text = 'Points Cards in Hand Are Worth';
            app.PointsCardsinHandAreWorthEditField = uieditfield(app.SettingsTab, 'numeric');
            app.PointsCardsinHandAreWorthEditField.Value = 3;
            app.RESETButton = uibutton(app.SettingsTab, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.T1sEditFieldLabel = uilabel(app.Tab);
            app.T1sEditFieldLabel.Text = 'T1s';
            app.T1sEditField = uieditfield(app.Tab, 'numeric');
            app.T2sEditFieldLabel = uilabel(app.Tab);
            app.T2sEditFieldLabel.Text = 'T2s';
            app.T2sEditField = uieditfield(app.Tab, 'numeric');
            app.CIHEditFieldLabel = uilabel(app.Tab);
            app.CIHEditFieldLabel.Text = 'CIH';
            app.CIHEditField = uieditfield(app.Tab, 'numeric');
            app.CIBEditFieldLabel = uilabel(app.Tab);
            app.CIBEditFieldLabel.Text = 'CIB';
            app.CIBEditField = uieditfield(app.Tab, 'numeric');
            app.NERTEditFieldLabel = uilabel(app.Tab);
            app.NERTEditFieldLabel.Text = 'NERT';
            app.NERTEditField = uieditfield(app.Tab, 'numeric');
            % Show the figure after all components are created
            app.NertMachine.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NertsV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NertMachine)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NertMachine)
        end
    end
end